classdef DomStudioFracStat1D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Title_label                    matlab.ui.control.Label
        Subtitle_label                 matlab.ui.control.Label
        ImportTXT_btn                  matlab.ui.control.Button
        Calculate_btn                  matlab.ui.control.Button
        Save_btn                       matlab.ui.control.Button
        NotesTextAreaLabel             matlab.ui.control.Label
        Notes                          matlab.ui.control.TextArea
        ScanlineNameLabel              matlab.ui.control.Label
        scanName                       matlab.ui.control.EditField
        BarcodePlotAxes                matlab.ui.control.UIAxes
        SpacingHistogram               matlab.ui.control.UIAxes
        Author_label                   matlab.ui.control.Label
        Section1Title_label            matlab.ui.control.Label
        Section2Title_label            matlab.ui.control.Label
        trendPositionSpacingMetre      matlab.ui.control.UIAxes
        trendPositionSpacingRank       matlab.ui.control.UIAxes
        Section2Subtitle1_label        matlab.ui.control.Label
        TrendR_label                   matlab.ui.control.Label
        TrendPval_label                matlab.ui.control.Label
        TrendHo_label                  matlab.ui.control.Label
        TrendHoText_label              matlab.ui.control.Label
        PatternR_label                 matlab.ui.control.Label
        Section2Subtitle2_label        matlab.ui.control.Label
        PatternPval_label              matlab.ui.control.Label
        PatternHo_label                matlab.ui.control.Label
        patternSpacingSpacingMetre     matlab.ui.control.UIAxes
        PatternHoText_label            matlab.ui.control.Label
        patternSpacingSpacingRank      matlab.ui.control.UIAxes
        UnifXksKstat_label             matlab.ui.control.Label
        Section2Subtitle3_label        matlab.ui.control.Label
        UnifXksPval_label              matlab.ui.control.Label
        UnifXksHo_label                matlab.ui.control.Label
        uniformXksPosition             matlab.ui.control.UIAxes
        UnifXksHoText_label            matlab.ui.control.Label
        uniformXksDerivative           matlab.ui.control.UIAxes
        MedianfilterofderivativeorderLabel  matlab.ui.control.Label
        MedianfilterofderivativeorderSlider  matlab.ui.control.Slider
        LoadFile_btn                   matlab.ui.control.Button
        Section2Subtitle4_label        matlab.ui.control.Label
        minXSliderLabel                matlab.ui.control.Label
        minXSlider                     matlab.ui.control.Slider
        minXSpinner                    matlab.ui.control.Spinner
        maxXSliderLabel                matlab.ui.control.Label
        maxXSlider                     matlab.ui.control.Slider
        maxXSpinner                    matlab.ui.control.Spinner
        PDF_label                      matlab.ui.control.Label
        Section3Title_label            matlab.ui.control.Label
        CalculateSub_btn               matlab.ui.control.Button
        subBarcodePlotAxes             matlab.ui.control.UIAxes
        subTrendR_label                matlab.ui.control.Label
        subSection2Subtitle1_label     matlab.ui.control.Label
        subTrendPval_label             matlab.ui.control.Label
        subTrendHo_label               matlab.ui.control.Label
        subTrendHoText_label           matlab.ui.control.Label
        subTrendPositionSpacingMetre   matlab.ui.control.UIAxes
        subPatternR_label              matlab.ui.control.Label
        subPatternPval_label           matlab.ui.control.Label
        subPatternHo_label             matlab.ui.control.Label
        subSection2Subtitle2_label     matlab.ui.control.Label
        subPatternHoText_label         matlab.ui.control.Label
        subTrendPositionSpacingRank    matlab.ui.control.UIAxes
        subPatternSpacingSpacingMetre  matlab.ui.control.UIAxes
        subUnifXksKstat_label          matlab.ui.control.Label
        subUnifXksPval_label           matlab.ui.control.Label
        subUnifXksHo_label             matlab.ui.control.Label
        subSection2Subtitle3_label     matlab.ui.control.Label
        subUnifXksHoText_label         matlab.ui.control.Label
        subPatternSpacingSpacingRank   matlab.ui.control.UIAxes
        subUniformXksDerivative        matlab.ui.control.UIAxes
        subUniformXksPosition          matlab.ui.control.UIAxes
        DistanceUnitExponentautoresetafterconversionLabel  matlab.ui.control.Label
        inExp                          matlab.ui.control.NumericEditField
        ScanlinePlungeLabel_2          matlab.ui.control.Label
        scanPlunge                     matlab.ui.control.NumericEditField
        ScanlineTrendLabel_2           matlab.ui.control.Label
        scanTrend                      matlab.ui.control.NumericEditField
        FractureDirectionLabel_2       matlab.ui.control.Label
        fracDir                        matlab.ui.control.NumericEditField
        FractureDipLabel_2             matlab.ui.control.Label
        fracDip                        matlab.ui.control.NumericEditField
        ScanlineDetailsSwitchLabel     matlab.ui.control.Label
        ScanlineDetailsSwitch          matlab.ui.control.Switch
        SpacingStats_label             matlab.ui.control.Label
        SubSpacingStats_label          matlab.ui.control.Label
        PoissPval_label                matlab.ui.control.Label
        PoissHo_label                  matlab.ui.control.Label
        subSection2Subtitle4_label     matlab.ui.control.Label
        subPoissonEvid_label           matlab.ui.control.Label
        subPoissonBase                 matlab.ui.control.UIAxes
        subPoissonPooled               matlab.ui.control.UIAxes
        PoissStats_chi2stat_label      matlab.ui.control.Label
        PoissStats_df_label            matlab.ui.control.Label
        PoissonT_label                 matlab.ui.control.Label
        PoissonBins_label              matlab.ui.control.Label
        PoissStats_0_label             matlab.ui.control.Label
        ExpLKstat_label                matlab.ui.control.Label
        ExpLPval_label                 matlab.ui.control.Label
        ExpLHo_label                   matlab.ui.control.Label
        subExpSpacingCDF               matlab.ui.control.UIAxes
        subSection2Subtitle5_label     matlab.ui.control.Label
        ExpLEvid_label                 matlab.ui.control.Label
        subExpSpacingPDF               matlab.ui.control.UIAxes
        ExpKSKstat_label               matlab.ui.control.Label
        ExpKSPval_label                matlab.ui.control.Label
        ExpKSHo_label                  matlab.ui.control.Label
        subSection2Subtitle6_label     matlab.ui.control.Label
        ExpKEvid_label                 matlab.ui.control.Label
        gammaKSKstat_label             matlab.ui.control.Label
        GammaKSPval_label              matlab.ui.control.Label
        GammaKSHo_label                matlab.ui.control.Label
        subGammaSpacingCDF             matlab.ui.control.UIAxes
        subSection2Subtitle7_label     matlab.ui.control.Label
        gammaKEvid_label               matlab.ui.control.Label
        subGammaSpacingPDF             matlab.ui.control.UIAxes
        SLognormKSKstat_label          matlab.ui.control.Label
        SLognormKSPval_label           matlab.ui.control.Label
        SLognormKSHo_label             matlab.ui.control.Label
        subSection2Subtitle8_label     matlab.ui.control.Label
        lognormKEvid_label             matlab.ui.control.Label
        subLognormSpacingCDF           matlab.ui.control.UIAxes
        subLognormSpacingPDF           matlab.ui.control.UIAxes
        NormLKstat_label               matlab.ui.control.Label
        NormKSKstat_label              matlab.ui.control.Label
        NormLPval_label                matlab.ui.control.Label
        NormKSPval_label               matlab.ui.control.Label
        NormLHo_label                  matlab.ui.control.Label
        NormKSHo_label                 matlab.ui.control.Label
        subSection2Subtitle9_label     matlab.ui.control.Label
        normLEvid_label                matlab.ui.control.Label
        subSection2Subtitle10_label    matlab.ui.control.Label
        normKEvid_label                matlab.ui.control.Label
        subNormSpacingCDF              matlab.ui.control.UIAxes
        subNormSpacingPDF              matlab.ui.control.UIAxes
        OriginShiftmLabel              matlab.ui.control.Label
        ShiftX                         matlab.ui.control.NumericEditField
        OutputsummaryLabel             matlab.ui.control.Label
        Summary                        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        file     % input file name (without extension)
        path     % input file path
        inX      % input scanline intercept data (position along scanline)
        nX       % n. of input data
        minInX   % min position of input data
        maxInX   % max position of input data
        
        CorrX    % corrected position data
        CorrL    % length of scanline
        xS       % center coordinates of "bricks" with spacing S
        S        % spacing
        meanS    % mean spacing
        stdS     % spacing std deviation
        skewS    % spacing skewness
        kurtS    % spacing kurtosis
        P10      % fracture intensity/density P10
        
        rankS   % rank order of S
        
        TrendR      % Spearman rank correlation test for TREND: R
        TrendPval   % Spearman rank correlation test for TREND: Pvalue
        TrendHo     % Spearman rank correlation test for TREND: Ho
        
        PatternR      % Spearman rank correlation test for PATTERN: R
        PatternPval   % Spearman rank correlation test for PATTERN: Pvalue
        PatternHo     % Spearman rank correlation test for PATTERN: Ho
        
        observXfreq     % empirical cumulative distribution parameters
        Xecdf
        UnifXdist
        expectXcdf
        expectXpdf
        UnifXksHo
        UnifXksPval
        UnifXksKstat
        DobservXfreq
        DexpectXcdf
        MedianDobservXfreq
        
        % these are for sub-scanline analysis
        
        subMinID
        subMaxID
        subCorrX
        subInX
        subCorrL
        subxS
        subS
        subNX
        subMeanS
        subStdS
        subSkewS
        subKurtS
        subP10
        subRankS

        subTrendR      % Spearman rank correlation test for TREND: R
        subTrendPval   % Spearman rank correlation test for TREND: Pvalue
        subTrendHo     % Spearman rank correlation test for TREND: Ho
        
        subPatternR      % Spearman rank correlation test for PATTERN: R
        subPatternPval   % Spearman rank correlation test for PATTERN: Pvalue
        subPatternHo     % Spearman rank correlation test for PATTERN: Ho
        
        subObservXfreq     % empirical cumulative distribution parameters
        subXecdf
        subUnifXdist
        subExpectXcdf
        subExpectXpdf
        subUnifXksHo
        subUnifXksPval
        subUnifXksKstat
        subDobservXfreq
        subDexpectXcdf
        subMedianDobservXfreq
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportTXT_btn
        function ImportTXT_btnPushed(app, event)
            
            % function to load dataset from ascii column file
            
            % lock other tools
            set(app.ImportTXT_btn, 'Enable', 'off')
            set(app.Calculate_btn, 'Enable', 'off')
            set(app.Save_btn, 'Enable', 'off')
            set(app.LoadFile_btn, 'Enable', 'off')
            set(app.MedianfilterofderivativeorderSlider, 'Enable', 'off')
            set(app.minXSlider, 'Enable', 'off')
            set(app.minXSpinner, 'Enable', 'off')
            set(app.maxXSlider, 'Enable', 'off')
            set(app.maxXSpinner, 'Enable', 'off')
            set(app.CalculateSub_btn, 'Enable', 'off')
            
            % check clear data
            % answer = questdlg('Clear this project and load new dataset?', 'Yes','No');
            answer = uiconfirm(app.UIFigure,'Clear this project and load new dataset?','Confirm Clear','Icon','warning');
            if strcmp(answer,'Cancel')
                
                % unlock other tools
                set(app.ImportTXT_btn, 'Enable', 'on')
                set(app.Calculate_btn, 'Enable', 'on')
                set(app.Save_btn, 'Enable', 'on')
                set(app.LoadFile_btn, 'Enable', 'on')
                set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
                set(app.minXSlider, 'Enable', 'on')
                set(app.minXSpinner, 'Enable', 'on')
                set(app.maxXSlider, 'Enable', 'on')
                set(app.maxXSpinner, 'Enable', 'on')
                set(app.CalculateSub_btn, 'Enable', 'on')
                
                return;
            end
            
            % load ASCII file with fracture position inX along scanline
            [app.file, app.path] = uigetfile('*.*');
            if app.file == 0
                uialert(app.UIFigure,'File not found','Invalid File');
                
                % unlock other tools
                set(app.ImportTXT_btn, 'Enable', 'on')
                set(app.Calculate_btn, 'Enable', 'on')
                set(app.Save_btn, 'Enable', 'on')
                set(app.LoadFile_btn, 'Enable', 'on')
                set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
                set(app.minXSlider, 'Enable', 'on')
                set(app.minXSpinner, 'Enable', 'on')
                set(app.maxXSlider, 'Enable', 'on')
                set(app.maxXSpinner, 'Enable', 'on')
                set(app.CalculateSub_btn, 'Enable', 'on')
                
                return;
            else
                filename = [app.path app.file];
                [~,app.file,~] = fileparts(filename);
                app.inX = importdata(filename);
            end
            
            % convert to row vector if needed
            if size(app.inX,1)>1, app.inX = app.inX'; end
            
            % sort from left to right
            app.inX = sort(app.inX);
            
            % check input for data with the same inX value
            duplicateX = app.inX(app.inX(1:end-1)==app.inX(2:end));
            if ~isempty(duplicateX)
                uialert(app.UIFigure,{'You have duplicate position values:';num2str(duplicateX);'Check input file and rerun analysis'},'ERROR');
                
                % unlock other tools
                set(app.ImportTXT_btn, 'Enable', 'on')
                set(app.Calculate_btn, 'Enable', 'on')
                set(app.Save_btn, 'Enable', 'on')
                set(app.LoadFile_btn, 'Enable', 'on')
                set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
                set(app.minXSlider, 'Enable', 'on')
                set(app.minXSpinner, 'Enable', 'on')
                set(app.maxXSlider, 'Enable', 'on')
                set(app.maxXSpinner, 'Enable', 'on')
                set(app.CalculateSub_btn, 'Enable', 'on')
                
                return
            end
            
            % number of input data
            app.nX = length(app.inX);
            
            % default data for scaling and Terzaghi correction
            app.scanName.Value = app.file;  % name of scanline - default = file
            app.ShiftX.Value = 0;                 % origin shift - default is 0 m
            app.inExp.Value = 0;            % scaling exponent - default is 0 = metres
            app.scanPlunge.Value = 0;       % scanline plunge - default 0
            app.scanTrend.Value =  0;       % scanline trend - default 0
            app.fracDip.Value = 90;         % fracture set mean dip - default 90
            app.fracDir.Value = 0;          % fracture set mean dip direction - default 0
                        
            % conversion to meters and reset scaling exponent to zero
            app.inX = app.inX*10^app.inExp.Value;
            app.inExp.Value = 0;
            
            % min and max X of input data
            app.minInX = min(app.inX);
            app.maxInX = max(app.inX);
            
            % convert scanline and mean plane to direction cosines
            scanlineDirectionCosines = lineation(app.scanPlunge.Value,app.scanTrend.Value);
            fracturesMeanDirectionCosines = pole(app.fracDip.Value,app.fracDir.Value);
            
            % angle between scanline and plane normal
            alpha = acos(abs(scanlineDirectionCosines'*fracturesMeanDirectionCosines));
            
            % Terzaghi correction including shift to set origin = 0
            inXshift = app.inX - app.ShiftX.Value; 
            app.CorrX = inXshift*cos(alpha);
            app.CorrL = app.CorrX(end)-app.CorrX(1);
            
            % set limits for sub-scanline sliders and spinners
            app.minXSlider.Limits = [min(app.CorrX) max(app.CorrX)];
            app.maxXSlider.Limits = [min(app.CorrX) max(app.CorrX)];            
            app.minXSlider.Limits = app.minXSlider.Limits;
            app.maxXSlider.Limits = app.maxXSlider.Limits;
            app.minXSlider.Value = min(app.CorrX);
            app.minXSpinner.Value = min(app.CorrX);
            app.maxXSlider.Value = max(app.CorrX);
            app.maxXSpinner.Value = max(app.CorrX);

            % lock
            set(app.scanName, 'Enable', 'off')
            set(app.ShiftX, 'Enable', 'off')
            set(app.inExp, 'Enable', 'off')
            set(app.scanPlunge, 'Enable', 'off')
            set(app.scanTrend, 'Enable', 'off')
            set(app.fracDir, 'Enable', 'off')
            set(app.fracDip, 'Enable', 'off')
            set(app.Notes, 'Enable', 'off')
                        
            % confirm import
            uialert(app.UIFigure,{['You have loaded ' num2str(app.nX) ' fracture data'],'Input data have been converted to metres and Terzaghi correction has been applied','Check scanline name, scaling, orientation and notes'},'Data loaded');
            
            % unlock other tools
            set(app.ImportTXT_btn, 'Enable', 'on')
            set(app.Calculate_btn, 'Enable', 'on')
            set(app.Save_btn, 'Enable', 'on')
            set(app.LoadFile_btn, 'Enable', 'on')
            set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
            set(app.minXSlider, 'Enable', 'on')
            set(app.minXSpinner, 'Enable', 'on')
            set(app.maxXSlider, 'Enable', 'on')
            set(app.maxXSpinner, 'Enable', 'on')
            set(app.CalculateSub_btn, 'Enable', 'on')
            

        end

        % Button pushed function: Calculate_btn
        function Calculate_btnPushed(app, event)
            
            % lock other tools
            set(app.ImportTXT_btn, 'Enable', 'off')
            set(app.Calculate_btn, 'Enable', 'off')
            set(app.Save_btn, 'Enable', 'off')
            set(app.LoadFile_btn, 'Enable', 'off')
            set(app.MedianfilterofderivativeorderSlider, 'Enable', 'off')
            set(app.minXSlider, 'Enable', 'off')
            set(app.minXSpinner, 'Enable', 'off')
            set(app.maxXSlider, 'Enable', 'off')
            set(app.maxXSpinner, 'Enable', 'off')
            set(app.CalculateSub_btn, 'Enable', 'off')
            
            % confirm dialog
            answer = uiconfirm(app.UIFigure,{'Statistics calculation and plottig will be performed on scanline.'},'Confirm','Icon','warning');
            if strcmp(answer,'Cancel')
                
                % unlock other tools
                set(app.ImportTXT_btn, 'Enable', 'on')
                set(app.Calculate_btn, 'Enable', 'on')
                set(app.Save_btn, 'Enable', 'on')
                set(app.LoadFile_btn, 'Enable', 'on')
                set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
                set(app.minXSlider, 'Enable', 'on')
                set(app.minXSpinner, 'Enable', 'on')
                set(app.maxXSlider, 'Enable', 'on')
                set(app.maxXSpinner, 'Enable', 'on')
                set(app.CalculateSub_btn, 'Enable', 'on')
                
                return;
            end
            
            % calculate & plot
            
            % plot labels
            MinCorrXlabel = num2str(app.CorrX(1),3);
            MaxCorrXlabel = num2str(app.CorrX(end),3);
            MinInXlabel = num2str(app.inX(1),3);
            MaxInXlabel = num2str(app.inX(end),3);
                        
            % calculate spacing as length and "baricenter" coordinate of "bricks" between two fractures
            app.xS = (app.CorrX(2:end)+app.CorrX(1:end-1))/2; % baricenter coordinate of bricks
            app.S = (app.CorrX(2:end)-app.CorrX(1:end-1)); % length of bricks
            
            % rank order of S
            [~,ii]=sort(app.S,'Ascend');
            [~,app.rankS]=sort(ii);
            
            %calculate stats and P10
            app.meanS = mean(app.S);
            app.stdS = std(app.S);
            app.skewS = skewness(app.S);
            app.kurtS = kurtosis(app.S);
            app.P10 = 1/app.meanS;
            
            app.SpacingStats_label.Text = ['Spacing statistics: n. data  = ' num2str(app.nX),...
                                           ' - mean = ' num2str(app.meanS),...
                                           ' - std.dev. = ' num2str(app.stdS),...
                                           ' - skewness = ' num2str(app.skewS),...
                                           ' - kurtosis = ' num2str(app.kurtS),...
                                           ' - P10 = ' num2str(app.P10)];
            
            %____________________________________________
            % "barcode" plot
            
            % plot position as a row vector
            barPlotX = app.CorrX;
            if size(barPlotX,1)>1, barPlotX = barPlotX'; end
            
            % aspect ratio of plot
            AR = app.CorrL/30;
            
            % plot
            plot(app.BarcodePlotAxes,[barPlotX; barPlotX],[ones(size(barPlotX)); -ones(size(barPlotX))]*AR,'-r')
            app.BarcodePlotAxes.XLim = [app.CorrX(1) app.CorrX(end)];
            app.BarcodePlotAxes.XLabel.String = 'Position X - corrected [m]';
            app.BarcodePlotAxes.YLabel.String = '';
            app.BarcodePlotAxes.YTick = [];
            axis(app.BarcodePlotAxes, 'tight');
            app.BarcodePlotAxes.Title.String = ['Scanline ' app.scanName.Value ' in range: ' MinCorrXlabel ' - ' MaxCorrXlabel ' (corrected) = ' MinInXlabel ' - ' MaxInXlabel ' (non-corrected)'];
            % text(app.BarcodePlotAxes,app.CorrX(1),-0.6,['\leftarrow Original X = ' num2str(app.minInX,'%0.2f')],'HorizontalAlignment','left','FontSize',18,'FontName','Times')
            % text(app.BarcodePlotAxes,app.CorrX(end),-0.6,['Original X = ' num2str(app.maxInX,'%0.2f') ' \rightarrow'],'HorizontalAlignment','right','FontSize',18,'FontName','Times')
            
            %____________________________________________
            % spacing histogram
            
            histogram(app.SpacingHistogram, app.S);
            app.SpacingHistogram.XGrid = 'on';
            app.SpacingHistogram.YGrid = 'on';
            app.SpacingHistogram.XLabel.String = 'fracture spacing S [m]';
            app.SpacingHistogram.YLabel.String = 'frequency';
            app.SpacingHistogram.Title.String = ['Scanline ' app.scanName.Value];
            
            %____________________________________________
            % Spearman rank correlation test for TREND
            
            % calculate correlation coefficient - xS is baricenter coordinate of bricks
            [app.TrendR,app.TrendPval] = corr(app.xS',app.S','Type','Spearman'); % data are converted to column vectors as required by corr function
            
            % test outcome
            % Ho = no correlation or no TREND
            % Ha = positive or negative correlation or TREND
            if app.TrendPval < 0.05
                app.TrendHo = 0; % strong evidence against Ho -> Ho rejected
            else
                app.TrendHo = 1; % no evidence against Ho -> Ho retained
            end
            
            % display results
            app.TrendR_label.Text = ['Spearman rank correlation coefficient r = ' num2str(app.TrendR)];
            app.TrendPval_label.Text = ['p-value = ' num2str(app.TrendPval)];
            app.TrendHo_label.Text = ['Ho = ' num2str(app.TrendHo)];
            if app.TrendHo == 0
                app.TrendHoText_label.Text = 'Strong evidence against Ho of no TREND -> Ho rejected -> a TREND is detected at 5% significance';
            else
                app.TrendHoText_label.Text = 'No evidence against Ho of no TREND -> Ho retained -> no TREND is detected at 5% significance';
            end
            
            % plot trendPositionSpacingMetre - xS is baricenter coordinate of bricks
            plot(app.trendPositionSpacingMetre, app.xS, app.S,'o')
            
            % plot trendPositionSpacingRank - xS is baricenter coordinate of bricks
            plot(app.trendPositionSpacingRank, 1:length(app.xS), app.rankS,'o')
            
            %____________________________________________
            % Spearman rank correlation test for PATTERN
            
            % calculate correlation coefficient
            [app.PatternR,app.PatternPval] = corr(app.S(1:end-1)',app.S(2:end)','Type','Spearman'); % data are converted to column vectors as required by corr function
            
            % test outcome
            % Ho = no correlation or no PATTERN
            % Ha = positive or negative correlation or PATTERN
            if app.PatternPval < 0.05
                app.PatternHo = 0; % strong evidence against Ho -> Ho rejected
            else
                app.PatternHo = 1; % no evidence against Ho -> Ho retained
            end
            
            % display results
            app.PatternR_label.Text = ['Spearman rank correlation coefficient r = ' num2str(app.PatternR)];
            app.PatternPval_label.Text = ['p-value = ' num2str(app.PatternPval)];
            app.PatternHo_label.Text = ['Ho = ' num2str(app.PatternHo)];
            if app.PatternHo == 0
                app.PatternHoText_label.Text = 'Strong evidence against Ho of no PATTERN -> Ho rejected -> a PATTERN is detected at 5% significance';
            else
                app.PatternHoText_label.Text = 'No evidence against Ho of no PATTERN -> Ho retained -> no PATTERN is detected at 5% significance';
            end
            
            % plot patternSpacingSpacingMetre
            plot(app.patternSpacingSpacingMetre, app.S(1:end-1), app.S(2:end), 'o')
            
            % plot patternSpacingSpacingRank
            plot(app.patternSpacingSpacingRank, app.rankS(1:end-1), app.rankS(2:end), 'o')
            
            
            %___________________________________________________
            % K-S test for UNIFORM fracture spatial distribution
            
            % create empirical cumulative distribution
            [app.observXfreq,app.Xecdf] = ecdf(app.CorrX);
            
            % make UNIFORM distribution
            app.UnifXdist = makedist('Uniform','lower',app.CorrX(1),'upper',app.CorrX(end));
            
            % create expected NORMAL CDF and PDF
            app.expectXcdf = cdf(app.UnifXdist,app.Xecdf);
            app.expectXpdf = pdf(app.UnifXdist,app.Xecdf);
            
            % K-S test empirical vs. expected exponentital
            [app.UnifXksHo,app.UnifXksPval,app.UnifXksKstat,~] = kstest(app.CorrX,'CDF',[app.Xecdf app.expectXcdf]);
            
            % derivatives of observXfreq and expectXcdf
            app.DobservXfreq = diff(app.observXfreq)./diff(app.Xecdf);
            app.DexpectXcdf = diff(app.expectXcdf)./diff(app.Xecdf);
            
            % median filter of derivative
            app.MedianDobservXfreq = medfilt1(app.DobservXfreq, app.MedianfilterofderivativeorderSlider.Value);
            
            % display results
            app.Section2Subtitle3_label.Text = ['Results of K-S test for UNIFORM fracture spatial distribution with lower bound = ' num2str(app.CorrX(1)) ' and upper bound = ' num2str(app.CorrX(end))];
            app.UnifXksKstat_label.Text = ['K statistics = ' num2str(app.UnifXksKstat)];
            app.UnifXksPval_label.Text = ['p-value = ' num2str(app.UnifXksPval)];
            app.UnifXksHo_label.Text = ['Ho = ' num2str(app.UnifXksHo)];
            if app.UnifXksHo == 0
                app.UnifXksHoText_label.Text = 'No evidence against Ho of no difference with UNIFORM distr. -> Ho retained -> a UNIFORM fracture distribution is detected at 5% significance';
            else
                app.UnifXksHoText_label.Text = 'Strong evidence against Ho of no difference with UNIFORM distr. -> Ho rejected -> no UNIFORM fracture distribution is detected at 5% significance';
            end
            
            % plotting
            hold(app.uniformXksPosition,'off');
            plot(app.uniformXksPosition, app.Xecdf, app.observXfreq,'b-');
            hold(app.uniformXksPosition,'on');
            plot(app.uniformXksPosition, app.Xecdf, app.expectXcdf,'r-');
            plot(app.uniformXksPosition, [app.minXSpinner.Value app.minXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            plot(app.uniformXksPosition, [app.maxXSpinner.Value app.maxXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            legend(app.uniformXksPosition, 'Empirical CDF', 'UNIFORM CDF', 'min(X)' ,'max(X)', 'Location','SE');
            
            hold(app.uniformXksDerivative,'off');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DobservXfreq,'b-');
            hold(app.uniformXksDerivative,'on');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DexpectXcdf,'r-');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.MedianDobservXfreq,'g-','LineWidth',3);
            ylim(app.uniformXksDerivative, [0 max(app.DexpectXcdf)*10])
            plot(app.uniformXksDerivative, [app.minXSpinner.Value app.minXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            plot(app.uniformXksDerivative, [app.maxXSpinner.Value app.maxXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            legend(app.uniformXksDerivative, 'Empirical CDF derivative', 'UNIFORM CDF derivative', 'Median filter of derivative', 'min(X)' ,'max(X)', 'Location', 'NE');

            % unlock other tools
            set(app.ImportTXT_btn, 'Enable', 'on')
            set(app.Calculate_btn, 'Enable', 'on')
            set(app.Save_btn, 'Enable', 'on')
            set(app.LoadFile_btn, 'Enable', 'on')
            set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
            set(app.minXSlider, 'Enable', 'on')
            set(app.minXSpinner, 'Enable', 'on')
            set(app.maxXSlider, 'Enable', 'on')
            set(app.maxXSpinner, 'Enable', 'on')
            set(app.CalculateSub_btn, 'Enable', 'on')

        end

        % Value changed function: 
        % MedianfilterofderivativeorderSlider
        function MedianfilterofderivativeorderSliderValueChanged(app, event)
            % get value from slider and convert to integer
            app.MedianfilterofderivativeorderSlider.Value = round(app.MedianfilterofderivativeorderSlider.Value);
            
            % median filter of derivative
            app.MedianDobservXfreq = medfilt1(app.DobservXfreq, app.MedianfilterofderivativeorderSlider.Value);

            % update plot
            hold(app.uniformXksDerivative,'off');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DobservXfreq,'b-');
            hold(app.uniformXksDerivative,'on');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DexpectXcdf,'r-');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.MedianDobservXfreq,'g-','LineWidth',3);
            ylim(app.uniformXksDerivative, [0 max(app.DexpectXcdf)*10])
            plot(app.uniformXksDerivative, [app.minXSpinner.Value app.minXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            plot(app.uniformXksDerivative, [app.maxXSpinner.Value app.maxXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            legend(app.uniformXksDerivative, 'Empirical CDF derivative', 'UNIFORM CDF derivative', 'Median filter of derivative', 'min(X)' ,'max(X)', 'Location', 'NE');
            
        end

        % Value changed function: minXSlider
        function minXSliderValueChanged(app, event)
            app.minXSpinner.Value = app.minXSlider.Value;
            
            if app.maxXSlider.Value < app.minXSlider.Value
                app.maxXSlider.Value = app.minXSlider.Value;
                app.maxXSpinner.Value = app.minXSlider.Value;
            end
            
            % plotting
            hold(app.uniformXksPosition,'off');
            plot(app.uniformXksPosition, app.Xecdf, app.observXfreq,'b-');
            hold(app.uniformXksPosition,'on');
            plot(app.uniformXksPosition, app.Xecdf, app.expectXcdf,'r-');
            plot(app.uniformXksPosition, [app.minXSpinner.Value app.minXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            plot(app.uniformXksPosition, [app.maxXSpinner.Value app.maxXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            legend(app.uniformXksPosition, 'Empirical CDF', 'UNIFORM CDF', 'min(X)' ,'max(X)', 'Location','SE');
            
            hold(app.uniformXksDerivative,'off');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DobservXfreq,'b-');
            hold(app.uniformXksDerivative,'on');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DexpectXcdf,'r-');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.MedianDobservXfreq,'g-','LineWidth',3);
            ylim(app.uniformXksDerivative, [0 max(app.DexpectXcdf)*10])
            plot(app.uniformXksDerivative, [app.minXSpinner.Value app.minXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            plot(app.uniformXksDerivative, [app.maxXSpinner.Value app.maxXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            legend(app.uniformXksDerivative, 'Empirical CDF derivative', 'UNIFORM CDF derivative', 'Median filter of derivative', 'min(X)' ,'max(X)', 'Location', 'NE');
            
        end

        % Value changed function: minXSpinner
        function minXSpinnerValueChanged(app, event)
            app.minXSlider.Value = app.minXSpinner.Value;
            
            if app.maxXSlider.Value < app.minXSlider.Value
                app.maxXSlider.Value = app.minXSlider.Value;
                app.maxXSpinner.Value = app.minXSlider.Value;
            end

            % plotting
            hold(app.uniformXksPosition,'off');
            plot(app.uniformXksPosition, app.Xecdf, app.observXfreq,'b-');
            hold(app.uniformXksPosition,'on');
            plot(app.uniformXksPosition, app.Xecdf, app.expectXcdf,'r-');
            plot(app.uniformXksPosition, [app.minXSpinner.Value app.minXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            plot(app.uniformXksPosition, [app.maxXSpinner.Value app.maxXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            legend(app.uniformXksPosition, 'Empirical CDF', 'UNIFORM CDF', 'min(X)' ,'max(X)', 'Location','SE');
            
            hold(app.uniformXksDerivative,'off');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DobservXfreq,'b-');
            hold(app.uniformXksDerivative,'on');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DexpectXcdf,'r-');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.MedianDobservXfreq,'g-','LineWidth',3);
            ylim(app.uniformXksDerivative, [0 max(app.DexpectXcdf)*10])
            plot(app.uniformXksDerivative, [app.minXSpinner.Value app.minXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            plot(app.uniformXksDerivative, [app.maxXSpinner.Value app.maxXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            legend(app.uniformXksDerivative, 'Empirical CDF derivative', 'UNIFORM CDF derivative', 'Median filter of derivative', 'min(X)' ,'max(X)', 'Location', 'NE');

        end

        % Value changed function: maxXSlider
        function maxXSliderValueChanged(app, event)
            app.maxXSpinner.Value = app.maxXSlider.Value;
            
            if app.minXSlider.Value > app.maxXSlider.Value
                app.minXSlider.Value = app.maxXSlider.Value;
                app.minXSpinner.Value = app.maxXSlider.Value;
            end
            
            % plotting
            hold(app.uniformXksPosition,'off');
            plot(app.uniformXksPosition, app.Xecdf, app.observXfreq,'b-');
            hold(app.uniformXksPosition,'on');
            plot(app.uniformXksPosition, app.Xecdf, app.expectXcdf,'r-');
            plot(app.uniformXksPosition, [app.minXSpinner.Value app.minXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            plot(app.uniformXksPosition, [app.maxXSpinner.Value app.maxXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            legend(app.uniformXksPosition, 'Empirical CDF', 'UNIFORM CDF', 'min(X)' ,'max(X)', 'Location','SE');
            
            hold(app.uniformXksDerivative,'off');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DobservXfreq,'b-');
            hold(app.uniformXksDerivative,'on');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DexpectXcdf,'r-');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.MedianDobservXfreq,'g-','LineWidth',3);
            ylim(app.uniformXksDerivative, [0 max(app.DexpectXcdf)*10])
            plot(app.uniformXksDerivative, [app.minXSpinner.Value app.minXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            plot(app.uniformXksDerivative, [app.maxXSpinner.Value app.maxXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            legend(app.uniformXksDerivative, 'Empirical CDF derivative', 'UNIFORM CDF derivative', 'Median filter of derivative', 'min(X)' ,'max(X)', 'Location', 'NE');
            
        end

        % Value changed function: maxXSpinner
        function maxXSpinnerValueChanged(app, event)
            app.maxXSlider.Value = app.maxXSpinner.Value;
            
            if app.minXSlider.Value > app.maxXSlider.Value
                app.minXSlider.Value = app.maxXSlider.Value;
                app.minXSpinner.Value = app.maxXSlider.Value;
            end
            
            % plotting
            hold(app.uniformXksPosition,'off');
            plot(app.uniformXksPosition, app.Xecdf, app.observXfreq,'b-');
            hold(app.uniformXksPosition,'on');
            plot(app.uniformXksPosition, app.Xecdf, app.expectXcdf,'r-');
            plot(app.uniformXksPosition, [app.minXSpinner.Value app.minXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            plot(app.uniformXksPosition, [app.maxXSpinner.Value app.maxXSpinner.Value], [min([min(app.observXfreq) min(app.expectXcdf)]) max([max(app.observXfreq) max(app.expectXcdf)])], 'k:','LineWidth',2);
            legend(app.uniformXksPosition, 'Empirical CDF', 'UNIFORM CDF', 'min(X)' ,'max(X)', 'Location','SE');
            
            hold(app.uniformXksDerivative,'off');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DobservXfreq,'b-');
            hold(app.uniformXksDerivative,'on');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.DexpectXcdf,'r-');
            plot(app.uniformXksDerivative, app.Xecdf(2:end), app.MedianDobservXfreq,'g-','LineWidth',3);
            ylim(app.uniformXksDerivative, [0 max(app.DexpectXcdf)*10])
            plot(app.uniformXksDerivative, [app.minXSpinner.Value app.minXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            plot(app.uniformXksDerivative, [app.maxXSpinner.Value app.maxXSpinner.Value], [0 max(app.DexpectXcdf)*10], 'k:','LineWidth',2);
            legend(app.uniformXksDerivative, 'Empirical CDF derivative', 'UNIFORM CDF derivative', 'Median filter of derivative', 'min(X)' ,'max(X)', 'Location', 'NE');
            
            % lock
            set(app.scanName, 'Enable', 'off')
            set(app.ShiftX, 'Enable', 'off')
            set(app.inExp, 'Enable', 'off')
            set(app.scanPlunge, 'Enable', 'off')
            set(app.scanTrend, 'Enable', 'off')
            set(app.fracDir, 'Enable', 'off')
            set(app.fracDip, 'Enable', 'off')
            set(app.Notes, 'Enable', 'off')

        end

        % Button pushed function: LoadFile_btn
        function LoadFile_btnButtonPushed(app, event)

            % lock other tools
            set(app.ImportTXT_btn, 'Enable', 'off')
            set(app.Calculate_btn, 'Enable', 'off')
            set(app.Save_btn, 'Enable', 'off')
            set(app.LoadFile_btn, 'Enable', 'off')
            set(app.MedianfilterofderivativeorderSlider, 'Enable', 'off')
            set(app.minXSlider, 'Enable', 'off')
            set(app.minXSpinner, 'Enable', 'off')
            set(app.maxXSlider, 'Enable', 'off')
            set(app.maxXSpinner, 'Enable', 'off')
            set(app.CalculateSub_btn, 'Enable', 'off')
            
            % load MAT file with app structure (saved with Save_btnButtonPushed function)
            [app.file, app.path] = uigetfile('*.mat');
            
            if app.file == 0
                uialert(app.UIFigure,'File not found','Invalid File');
                
                % unlock other tools
                set(app.ImportTXT_btn, 'Enable', 'on')
                set(app.Calculate_btn, 'Enable', 'on')
                set(app.Save_btn, 'Enable', 'on')
                set(app.LoadFile_btn, 'Enable', 'on')
                set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
                set(app.minXSlider, 'Enable', 'on')
                set(app.minXSpinner, 'Enable', 'on')
                set(app.maxXSlider, 'Enable', 'on')
                set(app.maxXSpinner, 'Enable', 'on')
                set(app.CalculateSub_btn, 'Enable', 'on')
                
                return;
            else
                filename = [app.path app.file];
                load(filename,'inX',...
                    'nX',...
                    'scanName',...
                    'ShiftX',...
                    'inExp',...
                    'scanPlunge',...
                    'scanTrend',...
                    'fracDip',...
                    'fracDir',...
                    'CorrX',...
                    'CorrL',...
                    'minXSlider_Limits',...
                    'maxXSlider_Limits',...
                    'minXSlider_Limits',...
                    'maxXSlider_Limits',...
                    'minXSlider',...
                    'minXSpinner',...
                    'maxXSlider',...
                    'maxXSpinner',...
                    'MedianfilterofderivativeorderSlider',...
                    'Notes');
                [~,app.file,~] = fileparts(filename);   % this sets app.file to the present file name as saved on disk
                
                app.inX = inX;
                app.nX = nX;
                app.scanName.Value = scanName;
                app.ShiftX.Value = ShiftX;
                app.inExp.Value = inExp;
                app.scanPlunge.Value = scanPlunge;
                app.scanTrend.Value = scanTrend;
                app.fracDip.Value = fracDip;
                app.fracDir.Value = fracDir;
                app.CorrX = CorrX;
                app.CorrL = CorrL;
                app.minXSlider.Limits = minXSlider_Limits;
                app.maxXSlider.Limits = maxXSlider_Limits;
                app.minXSlider.Limits = minXSlider_Limits;
                app.maxXSlider.Limits = maxXSlider_Limits;
                app.minXSlider.Value = minXSlider;
                app.minXSpinner.Value = minXSpinner;
                app.maxXSlider.Value = maxXSlider;
                app.maxXSpinner.Value = maxXSpinner;
                app.MedianfilterofderivativeorderSlider.Value = MedianfilterofderivativeorderSlider;
                app.Notes.Value = Notes;
                
                clear('inX',...
                    'nX',...
                    'scanName',...
                    'ShiftX',...
                    'inExp',...
                    'scanPlunge',...
                    'scanTrend',...
                    'fracDip',...
                    'fracDir',...
                    'CorrX',...
                    'CorrL',...
                    'minXSlider_Limits',...
                    'maxXSlider_Limits',...
                    'minXSlider_Limits',...
                    'maxXSlider_Limits',...
                    'minXSlider',...
                    'minXSpinner',...
                    'maxXSlider',...
                    'maxXSpinner',...
                    'MedianfilterofderivativeorderSlider',...
                    'Notes');
                
            end
            
            % confirm load
            uialert(app.UIFigure,['You have loaded ' num2str(app.nX) ' fracture data'],'Data loaded');
            
            % unlock other tools
            set(app.ImportTXT_btn, 'Enable', 'on')
            set(app.Calculate_btn, 'Enable', 'on')
            set(app.Save_btn, 'Enable', 'on')
            set(app.LoadFile_btn, 'Enable', 'on')
            set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
            set(app.minXSlider, 'Enable', 'on')
            set(app.minXSpinner, 'Enable', 'on')
            set(app.maxXSlider, 'Enable', 'on')
            set(app.maxXSpinner, 'Enable', 'on')
            set(app.CalculateSub_btn, 'Enable', 'on')
            
        end

        % Button pushed function: Save_btn
        function Save_btnButtonPushed(app, event)

            % lock other tools
            set(app.ImportTXT_btn, 'Enable', 'off')
            set(app.Calculate_btn, 'Enable', 'off')
            set(app.Save_btn, 'Enable', 'off')
            set(app.LoadFile_btn, 'Enable', 'off')
            set(app.MedianfilterofderivativeorderSlider, 'Enable', 'off')
            set(app.minXSlider, 'Enable', 'off')
            set(app.minXSpinner, 'Enable', 'off')
            set(app.maxXSlider, 'Enable', 'off')
            set(app.maxXSpinner, 'Enable', 'off')
            set(app.CalculateSub_btn, 'Enable', 'off')
            
            % load MAT file with app structure (saved with Save_btnButtonPushed function)
            [app.file, app.path] = uiputfile('*.mat');
            
            if app.file == 0
                uialert(app.UIFigure,'File not found','Invalid File');
                
                % unlock other tools
                set(app.ImportTXT_btn, 'Enable', 'on')
                set(app.Calculate_btn, 'Enable', 'on')
                set(app.Save_btn, 'Enable', 'on')
                set(app.LoadFile_btn, 'Enable', 'on')
                set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
                set(app.minXSlider, 'Enable', 'on')
                set(app.minXSpinner, 'Enable', 'on')
                set(app.maxXSlider, 'Enable', 'on')
                set(app.maxXSpinner, 'Enable', 'on')
                set(app.CalculateSub_btn, 'Enable', 'on')
                
                return;
            else
                filename = [app.path app.file];
                [~,app.file,~] = fileparts(filename);   % this sets app.file to the present file name as saved on disk
                
                inX = app.inX;
                nX = app.nX;
                scanName = app.scanName.Value;
                ShiftX = app.ShiftX.Value;
                inExp = app.inExp.Value;
                scanPlunge = app.scanPlunge.Value;
                scanTrend = app.scanTrend.Value;
                fracDip = app.fracDip.Value;
                fracDir = app.fracDir.Value;
                CorrX = app.CorrX;
                CorrL = app.CorrL;
                minXSlider_Limits = app.minXSlider.Limits;
                maxXSlider_Limits = app.maxXSlider.Limits;
                minXSlider_Limits = app.minXSlider.Limits;
                maxXSlider_Limits = app.maxXSlider.Limits;
                minXSlider = app.minXSlider.Value;
                minXSpinner = app.minXSpinner.Value;
                maxXSlider = app.maxXSlider.Value;
                maxXSpinner = app.maxXSpinner.Value;
                MedianfilterofderivativeorderSlider = app.MedianfilterofderivativeorderSlider.Value;
                Notes = app.Notes.Value;
                
                save(filename,'inX',...
                    'nX',...
                    'scanName',...
                    'ShiftX',...
                    'inExp',...
                    'scanPlunge',...
                    'scanTrend',...
                    'fracDip',...
                    'fracDir',...
                    'CorrX',...
                    'CorrL',...
                    'minXSlider_Limits',...
                    'maxXSlider_Limits',...
                    'minXSlider_Limits',...
                    'maxXSlider_Limits',...
                    'minXSlider',...
                    'minXSpinner',...
                    'maxXSlider',...
                    'maxXSpinner',...
                    'MedianfilterofderivativeorderSlider',...
                    'Notes');
                
                clear('inX',...
                    'nX',...
                    'scanName',...
                    'ShiftX',...
                    'inExp',...
                    'scanPlunge',...
                    'scanTrend',...
                    'fracDip',...
                    'fracDir',...
                    'CorrX',...
                    'CorrL',...
                    'minXSlider_Limits',...
                    'maxXSlider_Limits',...
                    'minXSlider_Limits',...
                    'maxXSlider_Limits',...
                    'minXSlider',...
                    'minXSpinner',...
                    'maxXSlider',...
                    'maxXSpinner',...
                    'MedianfilterofderivativeorderSlider',...
                    'Notes');
                
            end
            
            % confirm save
            uialert(app.UIFigure,['You have saved ' num2str(app.nX) ' fracture data'],'Data saved');
            
            % unlock other tools
            set(app.ImportTXT_btn, 'Enable', 'on')
            set(app.Calculate_btn, 'Enable', 'on')
            set(app.Save_btn, 'Enable', 'on')
            set(app.LoadFile_btn, 'Enable', 'on')
            set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
            set(app.minXSlider, 'Enable', 'on')
            set(app.minXSpinner, 'Enable', 'on')
            set(app.maxXSlider, 'Enable', 'on')
            set(app.maxXSpinner, 'Enable', 'on')
            set(app.CalculateSub_btn, 'Enable', 'on')
                        
        end

        % Button pushed function: CalculateSub_btn
        function CalculateSub_btnButtonPushed(app, event)
            
            % confirm dialog
            answer = uiconfirm(app.UIFigure,{'Statistics calculation and plottig will be performed on sub-scanline.'},'Confirm','Icon','warning');
            if strcmp(answer,'Cancel')
                return;
            end
            
            % calculate and plot stats for sub-scanline defined between min(X) and max(X)
            
            % extract subset of X and S
            app.subMinID = find(abs(app.CorrX - app.minXSlider.Value) == min(abs(app.CorrX - app.minXSlider.Value)));  % index of min(CorrX) = index of fracture closer to min(CorrX)
            app.subMaxID = find(abs(app.CorrX - app.maxXSlider.Value) == min(abs(app.CorrX - app.maxXSlider.Value)));  % index of max(CorrX) = index of fracture closer to max(CorrX)
                        
            % extract subset
            app.subCorrX = app.CorrX(app.subMinID:app.subMaxID);    % extract corrected position vector
            app.subInX = app.inX(app.subMinID:app.subMaxID);    % extract non-corrected position vector

            % check n. of fractures
            if length(app.subCorrX) < 10
                uialert(app.UIFigure,{['Number of fractures < 10';'Change range and rerun analysis']},'ERROR');
                return
            end
            
            % plot labels
            subMinXlabel = num2str(app.subCorrX(1),'%.2f');
            subMaxXlabel = num2str(app.subCorrX(end),'%.2f');
            subMinInXlabel = num2str(app.subInX(1),'%.2f');
            subMaxInXlabel = num2str(app.subInX(end),'%.2f');
            
            % calculate X and S subsets
            app.subCorrX = app.subCorrX - app.subCorrX(1);     % shift to convert to subCorrX(1) = 0
            app.subCorrL = app.subCorrX(end)-app.subCorrX(1);  % length
            
            app.subxS = (app.subCorrX(2:end)+app.subCorrX(1:end-1))/2;  % baricenter coordinate of bricks
            app.subS = (app.subCorrX(2:end)-app.subCorrX(1:end-1));     % length of bricks
            
            % rank order of S
            [~,ii]=sort(app.subS,'Ascend');
            [~,app.subRankS]=sort(ii);
            
            %calculate stats and P10
            app.subNX = length(app.subCorrX);
            app.subMeanS = mean(app.subS);
            app.subStdS = std(app.subS);
            app.subSkewS = skewness(app.subS);
            app.subKurtS = kurtosis(app.subS);
            app.subP10 = 1/app.subMeanS;
            
            app.SubSpacingStats_label.Text = ['Sub-scanline spacing statistics: n. data  = ' num2str(app.subNX),...
                                              ' -  mean = ' num2str(app.subMeanS),...
                                              ' - std.dev. = ' num2str(app.subStdS),...
                                              ' - skewness = ' num2str(app.subSkewS),...
                                              ' - kurtosis = ' num2str(app.subKurtS),...
                                              ' - P10 = ' num2str(app.subP10)];
                        
            % show boundaries, length and n. of fractures in figure
            
            %____________________________________________
            % "barcode" plot
            
            % plot X as a row vector
            subBarPlotX = app.subCorrX;
            if size(subBarPlotX,1)>1, subBarPlotX = subBarPlotX'; end
            
            % aspect ratio of plot
            AR = app.subCorrL/30;
            
            % plot
            plot(app.subBarcodePlotAxes,[subBarPlotX; subBarPlotX],[ones(size(subBarPlotX)); -ones(size(subBarPlotX))]*AR,'-r')
            app.subBarcodePlotAxes.XLim = [app.subCorrX(1) app.subCorrX(end)];
            app.subBarcodePlotAxes.XLabel.String = 'Position X - corrected [m]';
            app.subBarcodePlotAxes.YLabel.String = '';
            app.subBarcodePlotAxes.YTick = [];
            axis(app.subBarcodePlotAxes, 'tight');
            app.subBarcodePlotAxes.Title.String = ['Scanline ' app.scanName.Value ' in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            % text(app.subBarcodePlotAxes,app.subCorrX(1),-0.5,['\leftarrow Original X = ' num2str(app.subInX(1),'%0.2f')],'HorizontalAlignment','left','FontSize',18,'FontName','Times')
            % text(app.subBarcodePlotAxes,app.subCorrX(end),-0.5,['Original X = ' num2str(app.subInX(end),'%0.2f') ' \rightarrow'],'HorizontalAlignment','right','FontSize',18,'FontName','Times')

            %____________________________________________
            % Spearman rank correlation test for TREND
            
            % calculate correlation coefficient - subxS is baricenter coordinate of bricks
            [app.subTrendR,app.subTrendPval] = corr(app.subxS',app.subS','Type','Spearman'); % data are converted to column vectors as required by corr function
            
            % test outcome
            % Ho = no correlation or no TREND
            % Ha = positive or negative correlation or TREND
            if app.subTrendPval < 0.05
                app.subTrendHo = 0; % strong evidence against Ho -> Ho rejected
            else
                app.subTrendHo = 1; % no evidence against Ho -> Ho retained
            end
            
            % display results
            app.subSection2Subtitle1_label.Text = ['Non parametric Spearman rank correlation test for TREND in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.subTrendR_label.Text = ['Spearman rank correlation coefficient r = ' num2str(app.subTrendR)];
            app.subTrendPval_label.Text = ['p-value = ' num2str(app.subTrendPval)];
            app.subTrendHo_label.Text = ['Ho = ' num2str(app.subTrendHo)];
            if app.subTrendHo == 0
                app.subTrendHoText_label.Text = 'Strong evidence against Ho of no TREND -> Ho rejected -> a TREND is detected at 5% significance';
                subTrendTxt = 'trend';
            else
                app.subTrendHoText_label.Text = 'No evidence against Ho of no TREND -> Ho retained -> no TREND is detected at 5% significance';
                subTrendTxt = 'no_trend';
            end
            
            % plot subtrendPositionSpacingMetre - subxS is baricenter coordinate of bricks
            plot(app.subTrendPositionSpacingMetre, app.subxS, app.subS,'o')
            
            % plot subTrendPositionSpacingRank - subxS is baricenter coordinate of bricks
            plot(app.subTrendPositionSpacingRank, 1:length(app.subxS), app.subRankS,'o')
            
            %____________________________________________
            % Spearman rank correlation test for PATTERN
            
            % calculate correlation coefficient
            [app.subPatternR,app.subPatternPval] = corr(app.subS(1:end-1)',app.subS(2:end)','Type','Spearman'); % data are converted to column vectors as required by corr function
            
            % test outcome
            % Ho = no correlation or no PATTERN
            % Ha = positive or negative correlation or PATTERN
            if app.subPatternPval < 0.05
                app.subPatternHo = 0; % strong evidence against Ho -> Ho rejected
            else
                app.subPatternHo = 1; % no evidence against Ho -> Ho retained
            end
            
            % display results
            app.subSection2Subtitle2_label.Text = ['Non parametric Spearman rank correlation test for PATTERN in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.subPatternR_label.Text = ['Spearman rank correlation coefficient r = ' num2str(app.subPatternR)];
            app.subPatternPval_label.Text = ['p-value = ' num2str(app.subPatternPval)];
            app.subPatternHo_label.Text = ['Ho = ' num2str(app.subPatternHo)];
            if app.subPatternHo == 0
                app.subPatternHoText_label.Text = 'Strong evidence against Ho of no PATTERN -> Ho rejected -> a PATTERN is detected at 5% significance';
                subPatternTxt = 'pattern';
            else
                app.subPatternHoText_label.Text = 'No evidence against Ho of no PATTERN -> Ho retained -> no PATTERN is detected at 5% significance';
                subPatternTxt = 'no_pattern';
            end
            
            % plot subPatternSpacingSpacingMetre
            plot(app.subPatternSpacingSpacingMetre, app.subS(1:end-1), app.subS(2:end), 'o')
            
            % plot subPatternSpacingSpacingRank
            plot(app.subPatternSpacingSpacingRank, app.subRankS(1:end-1), app.subRankS(2:end), 'o')
            
            
            %___________________________________________________
            % K-S test for UNIFORM fracture spatial distribution
            
            % create empirical cumulative distribution
            [app.subObservXfreq,app.subXecdf] = ecdf(app.subCorrX);
            
            % make UNIFORM distribution
            app.subUnifXdist = makedist('Uniform','lower',app.subCorrX(1),'upper',app.subCorrX(end));
            
            % create expected NORMAL CDF and PDF
            app.subExpectXcdf = cdf(app.subUnifXdist,app.subXecdf);
            app.subExpectXpdf = pdf(app.subUnifXdist,app.subXecdf);
            
            % K-S test empirical vs. expected exponentital
            [app.subUnifXksHo,app.subUnifXksPval,app.subUnifXksKstat,~] = kstest(app.subCorrX,'CDF',[app.subXecdf app.subExpectXcdf]);
            
            % derivatives of subObservXfreq and subExpectXcdf
            app.subDobservXfreq = diff(app.subObservXfreq)./diff(app.subXecdf);
            app.subDexpectXcdf = diff(app.subExpectXcdf)./diff(app.subXecdf);
            
            % median filter of derivative - use same setting as general plot = app.MedianfilterofderivativeorderSlider.Value
            app.subMedianDobservXfreq = medfilt1(app.subDobservXfreq, app.MedianfilterofderivativeorderSlider.Value);
            
            % display results
            app.subSection2Subtitle3_label.Text = ['K-S test for UNIFORM spatial distribution in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.subUnifXksKstat_label.Text = ['K statistics = ' num2str(app.subUnifXksKstat)];
            app.subUnifXksPval_label.Text = ['p-value = ' num2str(app.subUnifXksPval)];
            app.subUnifXksHo_label.Text = ['Ho = ' num2str(app.subUnifXksHo)];
            if app.subUnifXksHo == 0
                app.subUnifXksHoText_label.Text = 'No evidence against Ho of no difference with UNIFORM distr. -> Ho retained -> a UNIFORM fracture distribution is detected at 5% significance';
                subUniformTxt = 'retained';
            else
                app.subUnifXksHoText_label.Text = 'Strong evidence against Ho of no difference with UNIFORM distr. -> Ho rejected -> no UNIFORM fracture distribution is detected at 5% significance';
                subUniformTxt = 'rejected';
            end
            
            % plotting
            hold(app.subUniformXksPosition,'off');
            plot(app.subUniformXksPosition, app.subXecdf, app.subObservXfreq,'b-');
            hold(app.subUniformXksPosition,'on');
            plot(app.subUniformXksPosition, app.subXecdf, app.subExpectXcdf,'r-');
            legend(app.subUniformXksPosition, 'Empirical CDF', 'UNIFORM CDF', 'Location','SE');
            
            hold(app.subUniformXksDerivative,'off');
            plot(app.subUniformXksDerivative, app.subXecdf(2:end), app.subDobservXfreq,'b-');
            hold(app.subUniformXksDerivative,'on');
            plot(app.subUniformXksDerivative, app.subXecdf(2:end), app.subDexpectXcdf,'r-');
            plot(app.subUniformXksDerivative, app.subXecdf(2:end), app.subMedianDobservXfreq,'g-','LineWidth',3);
            ylim(app.subUniformXksDerivative, [0 max(app.subDexpectXcdf)*10])
            legend(app.subUniformXksDerivative, 'Empirical CDF derivative', 'UNIFORM CDF derivative', 'Median filter of derivative', 'Location', 'NE');
            
            
            %___________________________________________________
            % Chi-squared test for POISSON fracture distribution
            
            % Poisson parameters
            PoissonT = floor(length(app.subCorrX)/5); % number of scanline segments for POISSON distribution counts
            PoissonSegmBnd = linspace(0,app.subCorrL,PoissonT+1);
            
            % assign fractures to segments
            for i=1:PoissonT
                PoissonJ(i) = length(find(and(app.subCorrX>=PoissonSegmBnd(i),app.subCorrX<PoissonSegmBnd(i+1))));
            end
            
            % create expected distribution from:
            % from http://faculty.business.utsa.edu/manderso//MATLAB/SalmonDigging/SalmonDigging.html
            % not using standard matlab
            
            % descriptive stats (observed counts in segments)
            PoissonObsFreqTable = tabulate(PoissonJ);
            PoissonLambda = mean(PoissonJ);
            PoissonN = length(PoissonJ);
            PoissonBins = PoissonObsFreqTable(:,1);
            
            % calculate expected counts according on POISSON distribution
            PoissonExpectPDF = pdf('Poisson', PoissonObsFreqTable(:,1), PoissonLambda);
            PoissonExpFreq = PoissonN*PoissonExpectPDF;
            
            % GOF test 1
            [PoissHo,PoissPval,PoissStats] = chi2gof(PoissonBins, 'Ctrs', PoissonBins, 'Frequency', PoissonObsFreqTable(:,2), 'Expected', PoissonExpFreq, 'NParams', 1, 'EMin',2);
            
            % display results
            app.subSection2Subtitle4_label.Text = ['Chi-squared test for POISSON spatial distribution in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.PoissonT_label.Text = ['Number of input segments = ' num2str(PoissonT)];
            app.PoissonBins_label.Text = ['Number of original segment classes = ' num2str(length(PoissonBins))];
            app.PoissStats_0_label.Text = ['Number of pooled segment classes = ' num2str(length(PoissStats.O))];
            
            app.PoissStats_chi2stat_label.Text = ['Chi-squared = ' num2str(PoissStats.chi2stat)];
            app.PoissStats_df_label.Text = ['DOF = ' num2str(PoissStats.df)];
            app.PoissPval_label.Text = ['p-value = ' num2str(PoissPval)];
            app.PoissHo_label.Text = ['Ho = ' num2str(PoissHo)];
            
            if PoissHo == 0
                app.subPoissonEvid_label.Text = 'No evidence against Ho of no difference with POISSON distr. -> Ho retained -> a POISSON fracture distribution is detected at 5% significance';
                subPoissonTxt = 'retained';
            else
                app.subPoissonEvid_label.Text = 'Strong evidence against Ho of no difference with POISSON distr. -> Ho rejected -> no POISSON spacing distribution is detected at 5% significance';
                subPoissonTxt = 'rejected';
            end
            
            % plotting 1
            bar(app.subPoissonBase, PoissonBins, [PoissonObsFreqTable(:,2), PoissonExpFreq], 'grouped')
            legend(app.subPoissonBase,'Observed', 'Expected', 'Location', 'NorthWest')
            
            % plotting 2
            for i=1:length(PoissStats.O)
                PoissonPoolBins{i} = [num2str(PoissStats.edges(i)) '-' num2str(PoissStats.edges(i+1))];
            end
            PoissonPoolBins = categorical({PoissonPoolBins{1:end}});
            
            bar(app.subPoissonPooled, PoissonPoolBins, [PoissStats.O', PoissStats.E'], 'grouped')
            legend(app.subPoissonPooled, 'Observed', 'Expected', 'Location', 'NorthWest')
            
            
            %_________________________________________________________________
            % empirical cumulative distribution of spacing for following tests

            % create empirical cumulative distribution
            [observSfreq,Secdf] = ecdf(app.subS);
            
            %________________________________________
            % K-S test for EXPONENTIAL spacing distribution

            % fit EXPONENTIAL distribution
            ExpSdist = fitdist(app.subS','Exponential');
            
            % display fitted EXPONENTIAL distribution parameters
            disp(' ')
            disp(['Fitted EXPONENTIAL distribution: Mu = ' num2str(ExpSdist.mu)])
            
            % create expected EXPONENTIAL CDF and PDF
            expectExpScdf = cdf(ExpSdist,Secdf);
            expectExpSpdf = pdf(ExpSdist,Secdf);
            
            % Lilliefors test
            [ExpLHo,ExpLPval,ExpLKstat,~] = lillietest(app.subS,'Distr','exp');
            
            % K-S test empirical vs. expected EXPONENTIAL
            [ExpKSHo,ExpKSPval,ExpKSKstat,~] = kstest(app.subS,'CDF',[Secdf expectExpScdf]);
            
            % display results Lilliefors
            app.subSection2Subtitle5_label.Text = ['Lilliefors test for EXPONENTIAL spacing distribution with μ = ' num2str(ExpSdist.mu) ' in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.ExpLKstat_label.Text = ['K statistics = ' num2str(ExpLKstat)];
            app.ExpLPval_label.Text = ['p-value = ' num2str(ExpLPval)];
            app.ExpLHo_label.Text = ['Ho = ' num2str(ExpLHo)];
            if ExpLHo == 0
                app.ExpLEvid_label.Text = 'No evidence against Ho of no difference with EXPONENTIAL distr. -> Ho retained -> a EXPONENTIAL spacing distribution is detected at 5% significance';
            else
                app.ExpLEvid_label.Text = 'Strong evidence against Ho of no difference with EXPONENTIAL distr. -> Ho rejected -> no EXPONENTIAL spacing distribution is detected at 5% significance';
            end
            
            % display results K-S
            disp(' ')
            app.subSection2Subtitle6_label.Text = ['K-S test for EXPONENTIAL spacing distribution with μ = ' num2str(ExpSdist.mu) ' in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.ExpKSKstat_label.Text = ['K statistics = ' num2str(ExpKSKstat)];
            app.ExpKSPval_label.Text = ['p-value = ' num2str(ExpKSPval)];
            app.ExpKSHo_label.Text = ['Ho = ' num2str(ExpKSHo)];
            if ExpKSHo == 0
                app.ExpKEvid_label.Text = 'No evidence against Ho of no difference with EXPONENTIAL distr. -> Ho retained -> a EXPONENTIAL spacing distribution is detected at 5% significance';
                subExponentialTxt = [num2str(ExpSdist.mu), ' ', num2str(ExpKSPval)];
            else
                app.ExpKEvid_label.Text = 'Strong evidence against Ho of no difference with EXPONENTIAL distr. -> Ho rejected -> no EXPONENTIAL spacing distribution is detected at 5% significance';
                subExponentialTxt = ['rejected', ' ', '-'];
            end
            
            % plotting 1
            hold(app.subExpSpacingCDF,'off');
            plot(app.subExpSpacingCDF, Secdf,observSfreq);
            hold(app.subExpSpacingCDF,'on');
            plot(app.subExpSpacingCDF, Secdf,expectExpScdf,'r');
            legend(app.subExpSpacingCDF, 'Empirical CDF','EXPONENTIAL CDF','Location','SE');
            
            % plotting 2
            hold(app.subExpSpacingPDF,'off');
            histogram(app.subExpSpacingPDF, app.subS,'Normalization','pdf');
            hold(app.subExpSpacingPDF,'on');
            plot(app.subExpSpacingPDF, Secdf,expectExpSpdf,'r');
            legend(app.subExpSpacingPDF, 'Histogram','EXPONENTIAL PDF','Location','NE');
            
            
            %________________________________________
            % K-S test for GAMMA spacing distribution
            
            % fit GAMMA distribution
            GammaSdist = fitdist(app.subS','Gamma');
            
            % create expected GAMMA CDF and PDF
            expectGammaScdf = cdf(GammaSdist,Secdf);
            expectGammaSpdf = pdf(GammaSdist,Secdf);
            
            % K-S test empirical vs. expected GAMMA
            [GammaKSHo,GammaKSPval,GammaKSKstat,~] = kstest(app.subS,'CDF',[Secdf expectGammaScdf]);
            
            % display results K-S
            app.subSection2Subtitle7_label.Text = ['K-S test for GAMMA spacing distribution with a = ' num2str(GammaSdist.a) ' b = ' num2str(GammaSdist.b) ' in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.gammaKSKstat_label.Text = ['K statistics = ' num2str(GammaKSKstat)];
            app.GammaKSPval_label.Text = ['p-value = ' num2str(GammaKSPval)];
            app.GammaKSHo_label.Text = ['Ho = ' num2str(GammaKSHo)];
            if GammaKSHo == 0
                app.gammaKEvid_label.Text = 'No evidence against Ho of no difference with GAMMA distr. -> Ho retained -> a GAMMA spacing distribution is detected at 5% significance';
                subGammaTxt = [num2str(GammaSdist.a), ' ', num2str(GammaSdist.b), ' ', num2str(GammaKSPval)];
            else
                app.gammaKEvid_label.Text = 'Strong evidence against Ho of no difference with GAMMA distr. -> Ho rejected -> no GAMMA spacing distribution is detected at 5% significance';
                subGammaTxt = ['rejected', ' ', '-', ' ', '-'];
            end
            
            % plotting 1
            hold(app.subGammaSpacingCDF,'off');
            plot(app.subGammaSpacingCDF, Secdf,observSfreq);
            hold(app.subGammaSpacingCDF,'on');
            plot(app.subGammaSpacingCDF, Secdf,expectGammaScdf,'r');
            legend(app.subGammaSpacingCDF, 'Empirical CDF','GAMMA CDF','Location','SE');
            
            % plotting 1
            hold(app.subGammaSpacingPDF,'off');
            histogram(app.subGammaSpacingPDF, app.subS,'Normalization','pdf');
            hold(app.subGammaSpacingPDF,'on');
            plot(app.subGammaSpacingPDF, Secdf,expectGammaSpdf,'r');
            legend(app.subGammaSpacingPDF, 'Histogram','GAMMA PDF','Location','NE');
            
            
            %____________________________________________
            % K-S test for LOGNORMAL spacing distribution
            
            % fit LOGNORMAL distribution
            LognormSdist = fitdist(app.subS','Lognormal');
            
            % create expected LOGNORMAL CDF and PDF
            expectLognormScdf = cdf(LognormSdist,Secdf);
            expectLognormSpdf = pdf(LognormSdist,Secdf);
            
            % K-S test empirical vs. expected LOGNORMAL
            [SLognormKSHo,SLognormKSPval,SLognormKSKstat,~] = kstest(app.subS,'CDF',[Secdf expectLognormScdf]);
            
            % display
            app.subSection2Subtitle8_label.Text = ['K-S test for LOGNORMAL spacing distribution with μ = ' num2str(LognormSdist.mu) ' - σ = ' num2str(LognormSdist.sigma) ' in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.SLognormKSKstat_label.Text = ['K statistics = ' num2str(SLognormKSKstat)];
            app.SLognormKSPval_label.Text = ['p-value = ' num2str(SLognormKSPval)];
            app.SLognormKSHo_label.Text = ['Ho = ' num2str(SLognormKSHo)];
            if SLognormKSHo == 0
                app.lognormKEvid_label.Text = 'No evidence against Ho of no difference with LOGNORMAL distr. -> Ho retained -> a LOGNORMAL spacing distribution is detected at 5% significance';
                subLognormalTxt = [num2str(LognormSdist.mu), ' ', num2str(LognormSdist.sigma), ' ', num2str(SLognormKSPval)];
            else
                app.lognormKEvid_label.Text = 'Strong evidence against Ho of no difference with LOGNORMAL distr. -> Ho rejected -> no LOGNORMAL spacing distribution is detected at 5% significance';
                subLognormalTxt = ['rejected', ' ', '-', ' ', '-'];
            end
            
            % plotting 1
            hold(app.subLognormSpacingCDF, 'off');
            plot(app.subLognormSpacingCDF, Secdf,observSfreq);
            hold(app.subLognormSpacingCDF, 'on');
            plot(app.subLognormSpacingCDF, Secdf,expectLognormScdf,'r');
            legend(app.subLognormSpacingCDF, 'Empirical CDF','LOGNORMAL CDF','Location','SE');
            
            % plotting 2
            hold(app.subLognormSpacingPDF, 'off');
            histogram(app.subLognormSpacingPDF, app.subS,'Normalization','pdf');
            hold(app.subLognormSpacingPDF, 'on');
            plot(app.subLognormSpacingPDF, Secdf,expectLognormSpdf,'r');
            legend(app.subLognormSpacingPDF, 'Histogram','LOGNORMAL PDF','Location','NE');
            
            
            %_________________________________________
            % K-S test for NORMAL spacing distribution
            
            % fit NORMAL distribution
            NormSdist = fitdist(app.subS','Normal');
            
            % create expected NORMAL CDF and PDF
            expectNormScdf = cdf(NormSdist,Secdf);
            expectNormSpdf = pdf(NormSdist,Secdf);
            
            % Lilliefors test
            [NormLHo,NormLPval,NormLKstat,~] = lillietest(app.subS,'Distr','norm');
            
            % K-S test empirical vs. expected NORMAL
            [NormKSHo,NormKSPval,NormKSKstat,~] = kstest(app.subS,'CDF',[Secdf expectNormScdf]);
            
            % display results Lilliefors
            app.subSection2Subtitle9_label.Text = ['Lilliefors test for NORMAL spacing distribution with μ = ' num2str(NormSdist.mu) ' - σ = ' num2str(NormSdist.sigma) ' in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.NormLKstat_label.Text = ['K statistics = ' num2str(NormLKstat)];
            app.NormLPval_label.Text = ['p-value = ' num2str(NormLPval)];
            app.NormLHo_label.Text = ['Ho = ' num2str(NormLHo)];
            if NormLHo == 0
                app.normLEvid_label.Text = 'No evidence against Ho of no difference with NORMAL distr. -> Ho retained -> a NORMAL spacing distribution is detected at 5% significance';
            else
                app.normLEvid_label.Text = 'Strong evidence against Ho of no difference with NORMAL distr. -> Ho rejected -> no NORMAL spacing distribution is detected at 5% significance';
            end
            
            % display results K-S
            app.subSection2Subtitle10_label.Text = ['K-S test for NORMAL spacing distribution with μ = ' num2str(NormSdist.mu) ' - σ = ' num2str(NormSdist.sigma) ' in range: ' subMinXlabel ' - ' subMaxXlabel ' (corrected) = ' subMinInXlabel ' - ' subMaxInXlabel ' (non-corrected)'];
            app.NormKSKstat_label.Text = ['K statistics = ' num2str(NormKSKstat)];
            app.NormKSPval_label.Text = ['p-value = ' num2str(NormKSPval)];
            app.NormKSHo_label.Text = ['Ho = ' num2str(NormKSHo)];
            if NormKSHo == 0
                app.normKEvid_label.Text = 'No evidence against Ho of no difference with NORMAL distr. -> Ho retained -> a NORMAL spacing distribution is detected at 5% significance';
                subNormalTxt = [num2str(NormSdist.mu), ' ', num2str(NormSdist.sigma), ' ', num2str(NormKSPval)];
            else
                app.normKEvid_label.Text = 'Strong evidence against Ho of no difference with NORMAL distr. -> Ho rejected -> no NORMAL spacing distribution is detected at 5% significance';
                subNormalTxt = ['rejected', ' ', '-', ' ', '-'];
            end
            
            % plotting 1
            hold(app.subNormSpacingCDF, 'off');
            plot(app.subNormSpacingCDF, Secdf,observSfreq);
            hold(app.subNormSpacingCDF, 'on');
            plot(app.subNormSpacingCDF, Secdf,expectNormScdf,'r');
            legend(app.subNormSpacingCDF, 'Empirical CDF','NORMAL CDF','Location','SE');
            
            % plotting 2
            hold(app.subNormSpacingPDF, 'off');
            histogram(app.subNormSpacingPDF, app.subS,'Normalization','pdf');
            hold(app.subNormSpacingPDF, 'on');
            plot(app.subNormSpacingPDF, Secdf,expectNormSpdf,'r');
            legend(app.subNormSpacingPDF, 'Histogram','NORMAL PDF','Location','NE');
            
            
            %_______________________________________________
            % output formatted summary to be copied in table
            
            app.Summary.Value = [app.scanName.Value, ' ',...
                                subMinInXlabel, ' ',...
                                subMaxInXlabel, ' ',...
                                num2str(app.subNX), ' ',...
                                subTrendTxt, ' ',...
                                subPatternTxt, ' ',...
                                subUniformTxt, ' ',...
                                subPoissonTxt, ' ',...
                                num2str(app.subMeanS), ' ',...
                                num2str(app.subStdS), ' ',...
                                num2str(app.subSkewS), ' ',...
                                num2str(app.subKurtS), ' ',...
                                num2str(app.subP10), ' ',...
                                subExponentialTxt, ' ',...
                                subGammaTxt, ' ',...
                                subLognormalTxt, ' ',...
                                subNormalTxt, ' ',...                                
                                ];
                            
                                          
        end

        % Value changed function: ScanlineDetailsSwitch
        function ScanlineDetailsSwitchValueChanged(app, event)
            lockValue = app.ScanlineDetailsSwitch.Value;

            switch lockValue
                case 'Unlock'
                    % lock
                    set(app.ImportTXT_btn, 'Enable', 'off')
                    set(app.Calculate_btn, 'Enable', 'off')
                    set(app.Save_btn, 'Enable', 'off')
                    set(app.LoadFile_btn, 'Enable', 'off')
                    set(app.MedianfilterofderivativeorderSlider, 'Enable', 'off')
                    set(app.minXSlider, 'Enable', 'off')
                    set(app.minXSpinner, 'Enable', 'off')
                    set(app.maxXSlider, 'Enable', 'off')
                    set(app.maxXSpinner, 'Enable', 'off')
                    set(app.CalculateSub_btn, 'Enable', 'off')
                    
                    % unlock
                    set(app.scanName, 'Enable', 'on')
                    set(app.ShiftX, 'Enable', 'on')
                    set(app.inExp, 'Enable', 'on')
                    set(app.scanPlunge, 'Enable', 'on')
                    set(app.scanTrend, 'Enable', 'on')
                    set(app.fracDir, 'Enable', 'on')
                    set(app.fracDip, 'Enable', 'on')
                    set(app.Notes, 'Enable', 'on')
                    
                case 'Lock'
                    % unlock
                    set(app.ImportTXT_btn, 'Enable', 'on')
                    set(app.Calculate_btn, 'Enable', 'on')
                    set(app.Save_btn, 'Enable', 'on')
                    set(app.LoadFile_btn, 'Enable', 'on')
                    set(app.MedianfilterofderivativeorderSlider, 'Enable', 'on')
                    set(app.minXSlider, 'Enable', 'on')
                    set(app.minXSpinner, 'Enable', 'on')
                    set(app.maxXSlider, 'Enable', 'on')
                    set(app.maxXSpinner, 'Enable', 'on')
                    set(app.CalculateSub_btn, 'Enable', 'on')
                    
                    % lock
                    set(app.scanName, 'Enable', 'off')
                    set(app.ShiftX, 'Enable', 'off')
                    set(app.inExp, 'Enable', 'off')
                    set(app.scanPlunge, 'Enable', 'off')
                    set(app.scanTrend, 'Enable', 'off')
                    set(app.fracDir, 'Enable', 'off')
                    set(app.fracDip, 'Enable', 'off')
                    set(app.Notes, 'Enable', 'off')
                    
                    % apply updated scaling and correction
                    
                    % conversion to meters and reset scaling exponent to zero
                    app.inX = app.inX*10^app.inExp.Value;
                    app.inExp.Value = 0;
                    
                    % convert to direction cosines
                    scanlineDirectionCosines = lineation(app.scanPlunge.Value,app.scanTrend.Value);
                    fracturesMeanDirectionCosines = pole(app.fracDip.Value,app.fracDir.Value);
                    
                    % angle between scanline and plane normal
                    alpha = acos(abs(scanlineDirectionCosines'*fracturesMeanDirectionCosines));
                    
                    % Terzaghi correction including shift to set origin = 0
                    inXshift = app.inX - app.ShiftX.Value;
                    app.CorrX = inXshift*cos(alpha);
                    app.CorrL = app.CorrX(end)*cos(alpha);
                    
                    % set limits for sub-scanline sliders and spinners
                    app.minXSlider.Limits = [min(app.CorrX) max(app.CorrX)];
                    app.maxXSlider.Limits = [min(app.CorrX) max(app.CorrX)];
                    app.minXSlider.Limits = app.minXSlider.Limits;
                    app.maxXSlider.Limits = app.maxXSlider.Limits;
                    app.minXSlider.Value = min(app.CorrX);
                    app.minXSpinner.Value = min(app.CorrX);
                    app.maxXSlider.Value = max(app.CorrX);
                    app.maxXSpinner.Value = max(app.CorrX);

                    % confirm correction
                    uialert(app.UIFigure,{'Input data have been converted to metres and shifted with first fracture position X = 0.',' ','Terzaghi correction has been applied.'},'Correction applied');

            end
            
        end

        % Callback function
        function printPDF_btnButtonPushed(app, event)
            savefig
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Title_label = uilabel(app.UIFigure);
            app.Title_label.Text = 'DomStudioFracStat1D';
            app.Subtitle_label = uilabel(app.UIFigure);
            app.Subtitle_label.Text = 'Statistical analysis of fracture data collected in 1D (scalines, wells)';
            app.ImportTXT_btn = uibutton(app.UIFigure, 'push');
            app.ImportTXT_btn.ButtonPushedFcn = createCallbackFcn(app, @ImportTXT_btnPushed, true);
            app.ImportTXT_btn.Text = {'Import TXT'; 'Data File'};
            app.Calculate_btn = uibutton(app.UIFigure, 'push');
            app.Calculate_btn.ButtonPushedFcn = createCallbackFcn(app, @Calculate_btnPushed, true);
            app.Calculate_btn.Text = {'Calculate & Plot'; 'Scanline'};
            app.Save_btn = uibutton(app.UIFigure, 'push');
            app.Save_btn.ButtonPushedFcn = createCallbackFcn(app, @Save_btnButtonPushed, true);
            app.Save_btn.Text = {'Save Present'; 'State to File'};
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Enable = 'off';
            app.NotesTextAreaLabel.Text = 'Notes';
            app.Notes = uitextarea(app.UIFigure);
            app.Notes.Enable = 'off';
            app.ScanlineNameLabel = uilabel(app.UIFigure);
            app.ScanlineNameLabel.Enable = 'off';
            app.ScanlineNameLabel.Text = 'Scanline Name';
            app.scanName = uieditfield(app.UIFigure, 'text');
            app.scanName.Enable = 'off';
            app.BarcodePlotAxes = uiaxes(app.UIFigure);
            xlabel(app.BarcodePlotAxes, 'Position X - corrected [m]')
            app.BarcodePlotAxes.YTick = [];
            app.SpacingHistogram = uiaxes(app.UIFigure);
            xlabel(app.SpacingHistogram, 'Spacing S [m]')
            ylabel(app.SpacingHistogram, 'Frequency')
            app.Author_label = uilabel(app.UIFigure);
            app.Author_label.Text = {'Please cite as: Bistacchi et al., 2020, Solid Earth, https://doi.org/10.5194/se-2020-83'; '© 2020, by Andrea Bistacchi'};
            app.Section1Title_label = uilabel(app.UIFigure);
            app.Section1Title_label.Text = {'_________________________________________________________________________________________________'; 'Input position barcode plot and spacing histogram _____________________________________________________'};
            app.Section2Title_label = uilabel(app.UIFigure);
            app.Section2Title_label.Text = {'_________________________________________________________________________________________________'; 'Check if stationary: test for trend pattern vs. uniform ____________________________________________________'};
            app.trendPositionSpacingMetre = uiaxes(app.UIFigure);
            xlabel(app.trendPositionSpacingMetre, 'Position X [m]')
            ylabel(app.trendPositionSpacingMetre, 'Spacing S [m]')
            app.trendPositionSpacingRank = uiaxes(app.UIFigure);
            xlabel(app.trendPositionSpacingRank, 'Position X [rank order]')
            ylabel(app.trendPositionSpacingRank, 'Spacing S [rank order]')
            app.Section2Subtitle1_label = uilabel(app.UIFigure);
            app.Section2Subtitle1_label.Text = 'Non parametric Spearman rank correlation test for TREND in fracture data:';
            app.TrendR_label = uilabel(app.UIFigure);
            app.TrendR_label.Text = 'Spearman rank correlation coefficient r = ';
            app.TrendPval_label = uilabel(app.UIFigure);
            app.TrendPval_label.Text = 'p-value = ';
            app.TrendHo_label = uilabel(app.UIFigure);
            app.TrendHo_label.Text = 'Ho = ';
            app.TrendHoText_label = uilabel(app.UIFigure);
            app.TrendHoText_label.Text = 'Evidence...';
            app.PatternR_label = uilabel(app.UIFigure);
            app.PatternR_label.Text = 'Spearman rank correlation coefficient r = ';
            app.Section2Subtitle2_label = uilabel(app.UIFigure);
            app.Section2Subtitle2_label.Text = 'Non parametric Spearman rank correlation test for PATTERN in fracture data:';
            app.PatternPval_label = uilabel(app.UIFigure);
            app.PatternPval_label.Text = 'p-value = ';
            app.PatternHo_label = uilabel(app.UIFigure);
            app.PatternHo_label.Text = 'Ho = ';
            app.patternSpacingSpacingMetre = uiaxes(app.UIFigure);
            xlabel(app.patternSpacingSpacingMetre, 'Spacing S_i [m]')
            ylabel(app.patternSpacingSpacingMetre, 'Spacing S_i _+ _1 [m]')
            app.PatternHoText_label = uilabel(app.UIFigure);
            app.PatternHoText_label.Text = 'Evidence...';
            app.patternSpacingSpacingRank = uiaxes(app.UIFigure);
            xlabel(app.patternSpacingSpacingRank, 'Spacing S_i [rank order]')
            ylabel(app.patternSpacingSpacingRank, 'Spacing S_i _+ _1 [rank order]')
            app.UnifXksKstat_label = uilabel(app.UIFigure);
            app.UnifXksKstat_label.Text = 'K statistics = ';
            app.Section2Subtitle3_label = uilabel(app.UIFigure);
            app.Section2Subtitle3_label.Text = 'K-S test for UNIFORM spatial distribution';
            app.UnifXksPval_label = uilabel(app.UIFigure);
            app.UnifXksPval_label.Text = 'p-value = ';
            app.UnifXksHo_label = uilabel(app.UIFigure);
            app.UnifXksHo_label.Text = 'Ho = ';
            app.uniformXksPosition = uiaxes(app.UIFigure);
            xlabel(app.uniformXksPosition, 'Position X [m]')
            ylabel(app.uniformXksPosition, 'Proportion of position')
            app.UnifXksHoText_label = uilabel(app.UIFigure);
            app.UnifXksHoText_label.Text = 'Evidence...';
            app.uniformXksDerivative = uiaxes(app.UIFigure);
            xlabel(app.uniformXksDerivative, 'Position X [m]')
            ylabel(app.uniformXksDerivative, 'Derivative of proportion of position')
            app.MedianfilterofderivativeorderLabel = uilabel(app.UIFigure);
            app.MedianfilterofderivativeorderLabel.Text = {'Median filter of'; 'derivative (order)'; ''};
            app.MedianfilterofderivativeorderSlider = uislider(app.UIFigure);
            app.MedianfilterofderivativeorderSlider.Limits = [1 20];
            app.MedianfilterofderivativeorderSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.MedianfilterofderivativeorderSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.MedianfilterofderivativeorderSlider.ValueChangedFcn = createCallbackFcn(app, @MedianfilterofderivativeorderSliderValueChanged, true);
            app.MedianfilterofderivativeorderSlider.MinorTicks = [];
            app.MedianfilterofderivativeorderSlider.Value = 1;
            app.LoadFile_btn = uibutton(app.UIFigure, 'push');
            app.LoadFile_btn.ButtonPushedFcn = createCallbackFcn(app, @LoadFile_btnButtonPushed, true);
            app.LoadFile_btn.Text = {'Load Previously'; 'Saved State'};
            app.Section2Subtitle4_label = uilabel(app.UIFigure);
            app.Section2Subtitle4_label.Text = 'Select sub-scanline in the CORRECTED range min(X) ≤ X ≤ max(X)';
            app.minXSliderLabel = uilabel(app.UIFigure);
            app.minXSliderLabel.Text = 'min(X)';
            app.minXSlider = uislider(app.UIFigure);
            app.minXSlider.Limits = [1 20];
            app.minXSlider.ValueChangedFcn = createCallbackFcn(app, @minXSliderValueChanged, true);
            app.minXSlider.Value = 1;
            app.minXSpinner = uispinner(app.UIFigure);
            app.minXSpinner.ValueChangedFcn = createCallbackFcn(app, @minXSpinnerValueChanged, true);
            app.maxXSliderLabel = uilabel(app.UIFigure);
            app.maxXSliderLabel.Text = 'max(X)';
            app.maxXSlider = uislider(app.UIFigure);
            app.maxXSlider.Limits = [1 20];
            app.maxXSlider.ValueChangedFcn = createCallbackFcn(app, @maxXSliderValueChanged, true);
            app.maxXSlider.Value = 1;
            app.maxXSpinner = uispinner(app.UIFigure);
            app.maxXSpinner.ValueChangedFcn = createCallbackFcn(app, @maxXSpinnerValueChanged, true);
            app.PDF_label = uilabel(app.UIFigure);
            app.PDF_label.Text = '(to save report, save PDF or HTML from browser)';
            app.Section3Title_label = uilabel(app.UIFigure);
            app.Section3Title_label.Text = {'_________________________________________________________________________________________________'; 'Sub-scanline statistics _____________________________________________________________________________'};
            app.CalculateSub_btn = uibutton(app.UIFigure, 'push');
            app.CalculateSub_btn.ButtonPushedFcn = createCallbackFcn(app, @CalculateSub_btnButtonPushed, true);
            app.CalculateSub_btn.Text = {'Calculate & Plot'; 'Sub-Scanline'};
            app.subBarcodePlotAxes = uiaxes(app.UIFigure);
            xlabel(app.subBarcodePlotAxes, 'Position X - corrected [m]')
            app.subBarcodePlotAxes.YTick = [];
            app.subTrendR_label = uilabel(app.UIFigure);
            app.subTrendR_label.Text = 'Spearman rank correlation coefficient r = ';
            app.subSection2Subtitle1_label = uilabel(app.UIFigure);
            app.subSection2Subtitle1_label.Text = 'Non parametric Spearman rank correlation test for TREND';
            app.subTrendPval_label = uilabel(app.UIFigure);
            app.subTrendPval_label.Text = 'p-value = ';
            app.subTrendHo_label = uilabel(app.UIFigure);
            app.subTrendHo_label.Text = 'Ho = ';
            app.subTrendHoText_label = uilabel(app.UIFigure);
            app.subTrendHoText_label.Text = 'Evidence...';
            app.subTrendPositionSpacingMetre = uiaxes(app.UIFigure);
            xlabel(app.subTrendPositionSpacingMetre, 'Position X [m]')
            ylabel(app.subTrendPositionSpacingMetre, 'Spacing S [m]')
            app.subPatternR_label = uilabel(app.UIFigure);
            app.subPatternR_label.Text = 'Spearman rank correlation coefficient r = ';
            app.subPatternPval_label = uilabel(app.UIFigure);
            app.subPatternPval_label.Text = 'p-value = ';
            app.subPatternHo_label = uilabel(app.UIFigure);
            app.subPatternHo_label.Text = 'Ho = ';
            app.subSection2Subtitle2_label = uilabel(app.UIFigure);
            app.subSection2Subtitle2_label.Text = 'Non parametric Spearman rank correlation test for PATTERN';
            app.subPatternHoText_label = uilabel(app.UIFigure);
            app.subPatternHoText_label.Text = 'Evidence...';
            app.subTrendPositionSpacingRank = uiaxes(app.UIFigure);
            xlabel(app.subTrendPositionSpacingRank, 'Position X [rank order]')
            ylabel(app.subTrendPositionSpacingRank, 'Spacing S [rank order]')
            app.subPatternSpacingSpacingMetre = uiaxes(app.UIFigure);
            xlabel(app.subPatternSpacingSpacingMetre, 'Spacing S_i [m]')
            ylabel(app.subPatternSpacingSpacingMetre, 'Spacing S_i _+ _1 [m]')
            app.subUnifXksKstat_label = uilabel(app.UIFigure);
            app.subUnifXksKstat_label.Text = 'K statistics = ';
            app.subUnifXksPval_label = uilabel(app.UIFigure);
            app.subUnifXksPval_label.Text = 'p-value = ';
            app.subUnifXksHo_label = uilabel(app.UIFigure);
            app.subUnifXksHo_label.Text = 'Ho = ';
            app.subSection2Subtitle3_label = uilabel(app.UIFigure);
            app.subSection2Subtitle3_label.Text = 'K-S test for UNIFORM fracture spatial distribution';
            app.subUnifXksHoText_label = uilabel(app.UIFigure);
            app.subUnifXksHoText_label.Text = 'Evidence...';
            app.subPatternSpacingSpacingRank = uiaxes(app.UIFigure);
            xlabel(app.subPatternSpacingSpacingRank, 'Spacing S_i [rank order]')
            ylabel(app.subPatternSpacingSpacingRank, 'Spacing S_i _+ _1 [rank order]')
            app.subUniformXksDerivative = uiaxes(app.UIFigure);
            xlabel(app.subUniformXksDerivative, 'Position X [m]')
            ylabel(app.subUniformXksDerivative, 'Derivative of proportion of position')
            app.subUniformXksPosition = uiaxes(app.UIFigure);
            xlabel(app.subUniformXksPosition, 'Position X [m]')
            ylabel(app.subUniformXksPosition, 'Proportion of position')
            app.DistanceUnitExponentautoresetafterconversionLabel = uilabel(app.UIFigure);
            app.DistanceUnitExponentautoresetafterconversionLabel.Enable = 'off';
            app.DistanceUnitExponentautoresetafterconversionLabel.Text = 'Distance Unit Exponent (auto-reset after conversion)';
            app.inExp = uieditfield(app.UIFigure, 'numeric');
            app.inExp.Enable = 'off';
            app.ScanlinePlungeLabel_2 = uilabel(app.UIFigure);
            app.ScanlinePlungeLabel_2.Enable = 'off';
            app.ScanlinePlungeLabel_2.Text = 'Scanline Plunge';
            app.scanPlunge = uieditfield(app.UIFigure, 'numeric');
            app.scanPlunge.Limits = [0 90];
            app.scanPlunge.Enable = 'off';
            app.ScanlineTrendLabel_2 = uilabel(app.UIFigure);
            app.ScanlineTrendLabel_2.Enable = 'off';
            app.ScanlineTrendLabel_2.Text = 'Scanline Trend';
            app.scanTrend = uieditfield(app.UIFigure, 'numeric');
            app.scanTrend.Limits = [0 360];
            app.scanTrend.Enable = 'off';
            app.FractureDirectionLabel_2 = uilabel(app.UIFigure);
            app.FractureDirectionLabel_2.Enable = 'off';
            app.FractureDirectionLabel_2.Text = 'Fracture Direction';
            app.fracDir = uieditfield(app.UIFigure, 'numeric');
            app.fracDir.Limits = [0 360];
            app.fracDir.Enable = 'off';
            app.FractureDipLabel_2 = uilabel(app.UIFigure);
            app.FractureDipLabel_2.Enable = 'off';
            app.FractureDipLabel_2.Text = 'Fracture Dip';
            app.fracDip = uieditfield(app.UIFigure, 'numeric');
            app.fracDip.Limits = [0 90];
            app.fracDip.Enable = 'off';
            app.ScanlineDetailsSwitchLabel = uilabel(app.UIFigure);
            app.ScanlineDetailsSwitchLabel.Text = {'Scanline'; 'Details'};
            app.ScanlineDetailsSwitch = uiswitch(app.UIFigure, 'slider');
            app.ScanlineDetailsSwitch.Items = {'Unlock', 'Lock'};
            app.ScanlineDetailsSwitch.ValueChangedFcn = createCallbackFcn(app, @ScanlineDetailsSwitchValueChanged, true);
            app.ScanlineDetailsSwitch.Value = 'Lock';
            app.SpacingStats_label = uilabel(app.UIFigure);
            app.SpacingStats_label.Text = 'Scanline spacing statistics:';
            app.SubSpacingStats_label = uilabel(app.UIFigure);
            app.SubSpacingStats_label.Text = 'Sub-scanline spacing statistics:';
            app.PoissPval_label = uilabel(app.UIFigure);
            app.PoissPval_label.Text = 'p-value = ';
            app.PoissHo_label = uilabel(app.UIFigure);
            app.PoissHo_label.Text = 'Ho = ';
            app.subSection2Subtitle4_label = uilabel(app.UIFigure);
            app.subSection2Subtitle4_label.Text = 'Chi-squared test for POISSON spatial distribution';
            app.subPoissonEvid_label = uilabel(app.UIFigure);
            app.subPoissonEvid_label.Text = 'Evidence...';
            app.subPoissonBase = uiaxes(app.UIFigure);
            xlabel(app.subPoissonBase, 'Fractures in segment')
            ylabel(app.subPoissonBase, 'Frequency')
            app.subPoissonPooled = uiaxes(app.UIFigure);
            xlabel(app.subPoissonPooled, 'Fractures in pooled segment class')
            ylabel(app.subPoissonPooled, 'Frequency')
            app.PoissStats_chi2stat_label = uilabel(app.UIFigure);
            app.PoissStats_chi2stat_label.Text = 'DOF = ';
            app.PoissStats_df_label = uilabel(app.UIFigure);
            app.PoissStats_df_label.Text = 'Chi-squared = ';
            app.PoissonT_label = uilabel(app.UIFigure);
            app.PoissonT_label.Text = 'Number of input segments = ';
            app.PoissonBins_label = uilabel(app.UIFigure);
            app.PoissonBins_label.Text = 'Number of original segment classes = ';
            app.PoissStats_0_label = uilabel(app.UIFigure);
            app.PoissStats_0_label.Text = 'Number of pooled segment classes = ';
            app.ExpLKstat_label = uilabel(app.UIFigure);
            app.ExpLKstat_label.Text = 'K statistics = ';
            app.ExpLPval_label = uilabel(app.UIFigure);
            app.ExpLPval_label.Text = 'p-value = ';
            app.ExpLHo_label = uilabel(app.UIFigure);
            app.ExpLHo_label.Text = 'Ho = ';
            app.subExpSpacingCDF = uiaxes(app.UIFigure);
            xlabel(app.subExpSpacingCDF, 'Spacing S [m]')
            ylabel(app.subExpSpacingCDF, 'CDF - cumulative density function')
            app.subSection2Subtitle5_label = uilabel(app.UIFigure);
            app.subSection2Subtitle5_label.Text = 'Lilliefors test for EXPONENTIAL spacing distribution';
            app.ExpLEvid_label = uilabel(app.UIFigure);
            app.ExpLEvid_label.Text = 'Evidence...';
            app.subExpSpacingPDF = uiaxes(app.UIFigure);
            xlabel(app.subExpSpacingPDF, 'Spacing S [m]')
            ylabel(app.subExpSpacingPDF, 'PDF - probability density function')
            app.ExpKSKstat_label = uilabel(app.UIFigure);
            app.ExpKSKstat_label.Text = 'K statistics = ';
            app.ExpKSPval_label = uilabel(app.UIFigure);
            app.ExpKSPval_label.Text = 'p-value = ';
            app.ExpKSHo_label = uilabel(app.UIFigure);
            app.ExpKSHo_label.Text = 'Ho = ';
            app.subSection2Subtitle6_label = uilabel(app.UIFigure);
            app.subSection2Subtitle6_label.Text = 'K-S test for EXPONENTIAL spacing distribution';
            app.ExpKEvid_label = uilabel(app.UIFigure);
            app.ExpKEvid_label.Text = 'Evidence...';
            app.gammaKSKstat_label = uilabel(app.UIFigure);
            app.gammaKSKstat_label.Text = 'K statistics = ';
            app.GammaKSPval_label = uilabel(app.UIFigure);
            app.GammaKSPval_label.Text = 'p-value = ';
            app.GammaKSHo_label = uilabel(app.UIFigure);
            app.GammaKSHo_label.Text = 'Ho = ';
            app.subGammaSpacingCDF = uiaxes(app.UIFigure);
            xlabel(app.subGammaSpacingCDF, 'Spacing S [m]')
            ylabel(app.subGammaSpacingCDF, 'CDF - cumulative density function')
            app.subSection2Subtitle7_label = uilabel(app.UIFigure);
            app.subSection2Subtitle7_label.Text = 'K-S test for GAMMA spacing distribution';
            app.gammaKEvid_label = uilabel(app.UIFigure);
            app.gammaKEvid_label.Text = 'Evidence...';
            app.subGammaSpacingPDF = uiaxes(app.UIFigure);
            xlabel(app.subGammaSpacingPDF, 'Spacing S [m]')
            ylabel(app.subGammaSpacingPDF, 'PDF - probability density function')
            app.SLognormKSKstat_label = uilabel(app.UIFigure);
            app.SLognormKSKstat_label.Text = 'K statistics = ';
            app.SLognormKSPval_label = uilabel(app.UIFigure);
            app.SLognormKSPval_label.Text = 'p-value = ';
            app.SLognormKSHo_label = uilabel(app.UIFigure);
            app.SLognormKSHo_label.Text = 'Ho = ';
            app.subSection2Subtitle8_label = uilabel(app.UIFigure);
            app.subSection2Subtitle8_label.Text = 'K-S test for LOGNORMAL spacing distribution';
            app.lognormKEvid_label = uilabel(app.UIFigure);
            app.lognormKEvid_label.Text = 'Evidence...';
            app.subLognormSpacingCDF = uiaxes(app.UIFigure);
            xlabel(app.subLognormSpacingCDF, 'Spacing S [m]')
            ylabel(app.subLognormSpacingCDF, 'CDF - cumulative density function')
            app.subLognormSpacingPDF = uiaxes(app.UIFigure);
            xlabel(app.subLognormSpacingPDF, 'Spacing S [m]')
            ylabel(app.subLognormSpacingPDF, 'PDF - probability density function')
            app.NormLKstat_label = uilabel(app.UIFigure);
            app.NormLKstat_label.Text = 'K statistics = ';
            app.NormKSKstat_label = uilabel(app.UIFigure);
            app.NormKSKstat_label.Text = 'K statistics = ';
            app.NormLPval_label = uilabel(app.UIFigure);
            app.NormLPval_label.Text = 'p-value = ';
            app.NormKSPval_label = uilabel(app.UIFigure);
            app.NormKSPval_label.Text = 'p-value = ';
            app.NormLHo_label = uilabel(app.UIFigure);
            app.NormLHo_label.Text = 'Ho = ';
            app.NormKSHo_label = uilabel(app.UIFigure);
            app.NormKSHo_label.Text = 'Ho = ';
            app.subSection2Subtitle9_label = uilabel(app.UIFigure);
            app.subSection2Subtitle9_label.Text = 'Lilliefors test for NORMAL spacing distribution';
            app.normLEvid_label = uilabel(app.UIFigure);
            app.normLEvid_label.Text = 'Evidence...';
            app.subSection2Subtitle10_label = uilabel(app.UIFigure);
            app.subSection2Subtitle10_label.Text = 'K-S test for NORMAL spacing distribution';
            app.normKEvid_label = uilabel(app.UIFigure);
            app.normKEvid_label.Text = 'Evidence...';
            app.subNormSpacingCDF = uiaxes(app.UIFigure);
            xlabel(app.subNormSpacingCDF, 'Spacing S [m]')
            ylabel(app.subNormSpacingCDF, 'CDF - cumulative density function')
            app.subNormSpacingPDF = uiaxes(app.UIFigure);
            xlabel(app.subNormSpacingPDF, 'Spacing S [m]')
            ylabel(app.subNormSpacingPDF, 'PDF - probability density function')
            app.OriginShiftmLabel = uilabel(app.UIFigure);
            app.OriginShiftmLabel.Enable = 'off';
            app.OriginShiftmLabel.Text = 'Origin Shift [m]';
            app.ShiftX = uieditfield(app.UIFigure, 'numeric');
            app.ShiftX.Enable = 'off';
            app.OutputsummaryLabel = uilabel(app.UIFigure);
            app.OutputsummaryLabel.Enable = 'off';
            app.OutputsummaryLabel.Text = {'Output'; 'summary'};
            app.Summary = uitextarea(app.UIFigure);
            app.Summary.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DomStudioFracStat1D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end